classdef chev < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChebyshevAppUIFigure  matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        GridLayout2           matlab.ui.container.GridLayout
        OrderEditField        matlab.ui.control.NumericEditField
        OrderEditFieldLabel   matlab.ui.control.Label
        fcEditField           matlab.ui.control.NumericEditField
        fcEditFieldLabel      matlab.ui.control.Label
        fEditField            matlab.ui.control.NumericEditField
        fEditFieldLabel       matlab.ui.control.Label
        GridLayout3           matlab.ui.container.GridLayout
        RippleEditField       matlab.ui.control.NumericEditField
        RippleEditFieldLabel  matlab.ui.control.Label
        ReturnButton          matlab.ui.control.Button
        RumusButton           matlab.ui.control.Button
        ResetButton           matlab.ui.control.Button
        OutAxes               matlab.ui.control.UIAxes
        InAxes                matlab.ui.control.UIAxes
        PhaseAxes             matlab.ui.control.UIAxes
        MagAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       % Koefisien filter
        b
        a
    
         % Parameter-parameter
        fs = 20000 % Frekuensi sampling
        N = 200 % Jumlah sampel
        T = 1 % Durasi sinyal
        order % orde chebychev filter
        ripple
    
        fc % Frekuensi cut-off
        f % Frekuensi sumber
        x % Sinyal sumber
        t % Waktu
    end
    
    methods (Access = private)
        
        function redraw(app)
         % Menghasilkan sinyal sinusoidal dengan frekuensi app.f dan waktu app.t
        app.x = sin(2 * pi * app.f * app.t);
        % Mengambil subset sinyal x dengan panjang app.N
        y = app.x(1:app.N);

        % Plot magnitude/frekuensi dan fase filter
        % Mendapatkan koefisien filter b dan a dengan fungsi cheby1
        [app.b, app.a] = cheby1(app.order, app.ripple, 2 * app.fc / app.fs, 'high');
        % Mendapatkan respons frekuensi h dan w dari filter dengan koefisien b dan a menggunakan freqz
        [h, w] = freqz(app.b, app.a, [], app.fs);
        
        % Plotting magnitude/frekuensi menggunakan semilogy
        semilogy(app.MagAxes, w, abs(h));
        % Plotting fase menggunakan plot
        plot(app.PhaseAxes, w, unwrap(angle(h))*180/pi)

        % Plot spektrum input/output
        % Menghitung resolusi frekuensi df
        df = app.fs/app.N;
        % Membuat array ff yang berisi rentang frekuensi dari -app.fs/2 hingga app.fs/2-df
        ff = -app.fs/2:df:app.fs/2-df;

        % Transformasi Fourier terhadap sinyal y dengan fft dan menggeser frekuensi menggunakan fftshift
        res = fftshift(fft(y));

        % Operasi filtering pada sinyal y dengan filter koefisien b dan a menggunakan filter
        out = filter(app.b, app.a, y);
        % Transformasi Fourier terhadap sinyal hasil filtering (out) dengan fft dan menggeser frekuensi menggunakan fftshift
        rso = fftshift(fft(out));

        % Plotting spektrum input dengan semilogy
        semilogy(app.InAxes, ff, abs(res)/app.N);
        % spektrum output dengan semilogy
        semilogy(app.OutAxes, ff, abs(rso)/app.N);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.t = linspace(0, 1, app.fs);
            app.fc = 0.05 * app.fs;
            app.f = app.fc;
            app.order = 8;
            app.ripple = 100;
            
            app.OutAxes.XLim = [0, app.fs/2];
            app.InAxes.XLim = [0, app.fs/2];
            
            app.fEditField.Value = app.f;
            app.fcEditField.Value = app.fc;
            app.OrderEditField.Value = app.order;
            app.RippleEditField.Value = app.ripple;
            
            redraw(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.fc = 0.05 * app.fs;
            app.f = app.fc;
            app.order = 8;
            app.ripple = 100;
            
            app.fcEditField.Value = app.fc;
            app.fEditField.Value = app.f;
            app.OrderEditField.Value = app.order;
            app.RippleEditField.Value = app.ripple;
            
            redraw(app); 
        end

        % Button pushed function: RumusButton
        function RumusButtonPushed(app, event)
            rumus;
            closereq;
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            menu;
            closereq;
        end

        % Value changed function: fEditField
        function fEditFieldValueChanged(app, event)
            value = app.fEditField.Value;
            app.f = value;
            
            redraw(app);
        end

        % Value changed function: fcEditField
        function fcEditFieldValueChanged(app, event)
            value = app.fcEditField.Value;
            app.fc = value;           
                        
            redraw(app);
        end

        % Value changed function: OrderEditField
        function OrderEditFieldValueChanged(app, event)
            value = app.OrderEditField.Value;
            app.order = value;
            
            redraw(app);
        end

        % Value changed function: RippleEditField
        function RippleEditFieldValueChanged(app, event)
            value = app.RippleEditField.Value;
            app.ripple = value;
            
             redraw(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChebyshevAppUIFigure = uifigure('Visible', 'off');
            app.ChebyshevAppUIFigure.Name = 'ChebyshevApp';
            app.GridLayout = uigridlayout(app.ChebyshevAppUIFigure);
            app.MagAxes = uiaxes(app.GridLayout);
            title(app.MagAxes, 'Magnitude')
            xlabel(app.MagAxes, 'f (Hz)')
            ylabel(app.MagAxes, 'dB')
            zlabel(app.MagAxes, 'Z')
            app.MagAxes.YLim = [-150 20];
            app.PhaseAxes = uiaxes(app.GridLayout);
            title(app.PhaseAxes, 'Phase')
            xlabel(app.PhaseAxes, 'f (Hz)')
            ylabel(app.PhaseAxes, 'degree')
            zlabel(app.PhaseAxes, 'Z')
            app.InAxes = uiaxes(app.GridLayout);
            title(app.InAxes, 'In')
            xlabel(app.InAxes, 'f (Hz)')
            ylabel(app.InAxes, 'dB')
            zlabel(app.InAxes, 'Z')
            app.OutAxes = uiaxes(app.GridLayout);
            title(app.OutAxes, 'Out')
            xlabel(app.OutAxes, 'f (Hz)')
            ylabel(app.OutAxes, 'dB')
            zlabel(app.OutAxes, 'Z')
            app.OutAxes.XLim = [0 1];
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ResetButton = uibutton(app.GridLayout3, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RumusButton = uibutton(app.GridLayout3, 'push');
            app.RumusButton.ButtonPushedFcn = createCallbackFcn(app, @RumusButtonPushed, true);
            app.RumusButton.Text = 'Rumus';
            app.ReturnButton = uibutton(app.GridLayout3, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.RippleEditFieldLabel = uilabel(app.GridLayout3);
            app.RippleEditFieldLabel.Text = 'Ripple';
            app.RippleEditField = uieditfield(app.GridLayout3, 'numeric');
            app.RippleEditField.ValueChangedFcn = createCallbackFcn(app, @RippleEditFieldValueChanged, true);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.fEditFieldLabel = uilabel(app.GridLayout2);
            app.fEditFieldLabel.Text = 'f';
            app.fEditField = uieditfield(app.GridLayout2, 'numeric');
            app.fEditField.ValueChangedFcn = createCallbackFcn(app, @fEditFieldValueChanged, true);
            app.fcEditFieldLabel = uilabel(app.GridLayout2);
            app.fcEditFieldLabel.Text = 'fc';
            app.fcEditField = uieditfield(app.GridLayout2, 'numeric');
            app.fcEditField.ValueChangedFcn = createCallbackFcn(app, @fcEditFieldValueChanged, true);
            app.OrderEditFieldLabel = uilabel(app.GridLayout2);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.GridLayout2, 'numeric');
            app.OrderEditField.ValueChangedFcn = createCallbackFcn(app, @OrderEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.ChebyshevAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = chev

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChebyshevAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChebyshevAppUIFigure)
        end
    end
end